classdef PHIPS_Image_Classifier_8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        ChooseFolderforPHIPSDataButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        HousekeepingAttributesPanel     matlab.ui.container.Panel
        MultipleparticlesPanel          matlab.ui.container.Panel
        YesButton                       matlab.ui.control.Button
        NoButton                        matlab.ui.control.Button
        CutoffPanel                     matlab.ui.container.Panel
        YesButton_2                     matlab.ui.control.Button
        NoButton_2                      matlab.ui.control.Button
        ElongatedPanel                  matlab.ui.container.Panel
        YesButton_3                     matlab.ui.control.Button
        NoButton_3                      matlab.ui.control.Button
        ShatteringPanel                 matlab.ui.container.Panel
        YesButton_9                     matlab.ui.control.Button
        NoButton_9                      matlab.ui.control.Button
        AttributesPanel                 matlab.ui.container.Panel
        RimedPanel                      matlab.ui.container.Panel
        YesButton_4                     matlab.ui.control.Button
        NoButton_4                      matlab.ui.control.Button
        AggregatePanel                  matlab.ui.container.Panel
        YesButton_5                     matlab.ui.control.Button
        NoButton_5                      matlab.ui.control.Button
        PristinePanel                   matlab.ui.container.Panel
        YesButton_6                     matlab.ui.control.Button
        NoButton_6                      matlab.ui.control.Button
        ChainAggregatePanel             matlab.ui.container.Panel
        YesButton_7                     matlab.ui.control.Button
        NoButton_7                      matlab.ui.control.Button
        SublimatingPanel                matlab.ui.container.Panel
        YesButton_8                     matlab.ui.control.Button
        NoButton_8                      matlab.ui.control.Button
        ConfidenceLevelPanel            matlab.ui.container.Panel
        LowButton                       matlab.ui.control.Button
        MediumButton                    matlab.ui.control.Button
        HighButton                      matlab.ui.control.Button
        MakeNewClassificationButton     matlab.ui.control.Button
        ClassificationFileName          matlab.ui.control.EditField
        text_or                         matlab.ui.control.Label
        LoadExistingClassificationButton  matlab.ui.control.Button
        SkipSidePlaneCheckBox           matlab.ui.control.CheckBox
        SkipBRosetCheckBox              matlab.ui.control.CheckBox
        SkipNonpristineCheckBox         matlab.ui.control.CheckBox
        SelectionPanel                  matlab.ui.container.Panel
        PlateButton                     matlab.ui.control.Button
        ColumnButton                    matlab.ui.control.Button
        FrozenDropletButton             matlab.ui.control.Button
        BulletRosetteButton             matlab.ui.control.Button
        NeedleButton                    matlab.ui.control.Button
        IrregularButton                 matlab.ui.control.Button
        HollowColumnButton              matlab.ui.control.Button
        SkeletonPlateButton             matlab.ui.control.Button
        SectoredPlateButton             matlab.ui.control.Button
        SheathButton                    matlab.ui.control.Button
        DropletButton                   matlab.ui.control.Button
        SidePlaneButton                 matlab.ui.control.Button
        CappedColumnButton              matlab.ui.control.Button
        DendriteButton                  matlab.ui.control.Button
        GraupelButton                   matlab.ui.control.Button
        HabitCategoryLabel              matlab.ui.control.Label
        HoldAllowmultipleHabitsButton   matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        EmptyButton                     matlab.ui.control.Button
        NotClassifiedButton             matlab.ui.control.Button
        ClassifierEditFieldLabel        matlab.ui.control.Label
        ClassifierEditField             matlab.ui.control.EditField
        PHIPSDataFolderEditFieldLabel   matlab.ui.control.Label
        PHIPSDataFolderEditField        matlab.ui.control.EditField
        ClassifiedasEditFieldLabel      matlab.ui.control.Label
        ClassifiedasEditField           matlab.ui.control.EditField
        SkipNotClassifiedCheckBox       matlab.ui.control.CheckBox
        SkipDropletCheckBox             matlab.ui.control.CheckBox
        SkipIceCheckBox                 matlab.ui.control.CheckBox
        SkipIrregularCheckBox           matlab.ui.control.CheckBox
        SkipShatteringCheckBox          matlab.ui.control.CheckBox
        SkipEmptyCheckBox               matlab.ui.control.CheckBox
        SkipAggregateCheckBox           matlab.ui.control.CheckBox
        SkipMultipleCheckBox            matlab.ui.control.CheckBox
        SkipRimedCheckBox               matlab.ui.control.CheckBox
        SkipCutoffCheckBox              matlab.ui.control.CheckBox
        SkipPristineCheckBox            matlab.ui.control.CheckBox
        SkipElongatedCheckBox           matlab.ui.control.CheckBox
        AggregateLampLabel              matlab.ui.control.Label
        AggregateLamp                   matlab.ui.control.Lamp
        RimedLampLabel                  matlab.ui.control.Label
        RimedLamp                       matlab.ui.control.Lamp
        PristineLampLabel               matlab.ui.control.Label
        PristineLamp                    matlab.ui.control.Lamp
        MultipleLampLabel               matlab.ui.control.Label
        MultipleLamp                    matlab.ui.control.Lamp
        CutoffLampLabel                 matlab.ui.control.Label
        CutoffLamp                      matlab.ui.control.Lamp
        ElongatedLampLabel              matlab.ui.control.Label
        ElongatedLamp                   matlab.ui.control.Lamp
        ChainAggLampLabel               matlab.ui.control.Label
        ChainAggLamp                    matlab.ui.control.Lamp
        SublimatingLampLabel            matlab.ui.control.Label
        SublimatingLamp                 matlab.ui.control.Lamp
        ImageEditFieldLabel             matlab.ui.control.Label
        ImageEditField                  matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        LowLampLabel                    matlab.ui.control.Label
        LowLamp                         matlab.ui.control.Lamp
        MediumLampLabel                 matlab.ui.control.Label
        MediumLamp                      matlab.ui.control.Lamp
        HighLampLabel                   matlab.ui.control.Label
        HighLamp                        matlab.ui.control.Lamp
        ImageTimeEditFieldLabel         matlab.ui.control.Label
        ImageTimeEditField              matlab.ui.control.EditField
        FlagasinterestingButton         matlab.ui.control.Button
        deselectingholdoffnextimageLabel  matlab.ui.control.Label
        ShatteringLampLabel             matlab.ui.control.Label
        ShatteringLamp                  matlab.ui.control.Lamp
        InterestingFlagLampLabel        matlab.ui.control.Label
        InterestingFlagLamp             matlab.ui.control.Lamp
        IrregularPotentialShatteringButton  matlab.ui.control.Button
        ReadMeTab                       matlab.ui.container.Tab
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
    end

    
    methods (Access = public)
        
        %% Function for showing existing classification
        function String = show_classification(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.Plate(INDEX)==1
                String = 'Plate';
            elseif TULOS.Column(INDEX)==1
                String = 'Column';
            elseif TULOS.Hollow_Column(INDEX)==1
                String = 'Hollow Column';
            elseif TULOS.Frozen_droplet(INDEX)==1
                String = 'Frozen droplet';
            elseif TULOS.Bullet_rosette(INDEX)==1
                String = 'Bullet rosette';
            elseif TULOS.Needle(INDEX)==1
                String = 'Needle';
            elseif TULOS.Irregular(INDEX)==1
                String = 'Irregular';
            elseif TULOS.Skeleton_Plate(INDEX)==1
                String = 'Skeleton Plate';
            elseif TULOS.Sectored_Plate(INDEX)==1
                String = 'Sectored Plate';
            elseif TULOS.Sheath(INDEX)==1
                String = 'Sheath';
            elseif TULOS.SidePlane(INDEX)==1
                String = 'Side Plane';
            elseif TULOS.CappedColumn(INDEX)==1
                String = 'Capped Column';
            elseif TULOS.Graupel(INDEX)==1
                String = 'Graupel';
            elseif TULOS.Droplet(INDEX)==1
                String = 'Droplet';
            elseif TULOS.Empty(INDEX)==1
                String = 'Empty';
            else
                String = 'Not classified';
            end
            % Aggregate?
            if TULOS.Aggregate==1
                app.AggregateLamp.Color = [0 1 0];
            else app.AggregateLamp.Color = [0.9 0.9 0.9];
            end
            % Rimed?
            if TULOS.Rimed==1
                app.RimedLamp.Color = [0 1 0];
            else app.RimedLamp.Color = [0.9 0.9 0.9];
            end
            % Multiple?
            if TULOS.Multiple==1
                app.MultipleLamp.Color = [0 1 0];
            else app.MultipleLamp.Color = [0.9 0.9 0.9];
            end
            % Cutoff?
            if TULOS.Cutoff==1
                app.CutoffLamp.Color = [0 1 0];
            else app.CutoffLamp.Color = [0.9 0.9 0.9];
            end
            % Elongated?
            if TULOS.Elongated==1
                app.ElongatedLamp.Color = [0 1 0];
            else app.ElongatedLamp.Color = [0.9 0.9 0.9];
            end
            % Chain Aggregate
            if TULOS.ChainAgg==1
                app.ChainAggLamp.Color = [0 1 0];
            else app.ChainAggLamp.Color = [0.9 0.9 0.9];
            end
            % Sublimating
            if TULOS.Sublimating==1
                app.SublimatingLamp.Color = [0 1 0];
            else app.SublimatingLamp.Color = [0.9 0.9 0.9];
            end
            
        end
        
        %% Function for checking if Image_Classification txt-file exists
        function [fileexists, filename,classifier] = onkoolemassa(app)
            filename = dir('*_Image_Classification*');
            poista = sum(cell2mat(strfind({filename.name},{'._'})));
            if poista>0
                filename(1:poista) = [];
            end
            if ~isempty(filename)
                fileexists = 1;
                classifier = [];
                for i=1:size(filename,1)
                    filename2 = filename(i).name;
                    temp = strsplit(filename2,'_');
                    if length(temp) == 5 % classification performed by
                        temp2 = strsplit(temp{end},'.');
                        classifier{i} = temp2{1};
                    else
                        classifier = '';
                    end
                end
                app.ChooseClassificationDropDown.Items = classifier;
            else fileexists = 0;
            end
            
        end
        
        %% Functions for lighting the lamps
        function onkorimed(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.Rimed(INDEX) == 1
                app.RimedLamp.Color = [0 1 0];
            else app.RimedLamp.Color = [0.7,0.75,0.7];
            end
        end
        
        function onkoagregaatti(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.Aggregate(INDEX) == 1
                app.AggregateLamp.Color = [0 1 0];
            else app.AggregateLamp.Color = [0.7,0.75,0.7];
            end
        end
        
        function onkopristine(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.Pristine(INDEX) == 1
                app.PristineLamp.Color = [0 1 0];
            else app.PristineLamp.Color = [0.7,0.75,0.7];
            end
        end
        
        function onkomultiple(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.Multiple(INDEX) == 1
                app.MultipleLamp.Color = [0 1 0];
            else app.MultipleLamp.Color = [0.7,0.75,0.7];
            end
        end
        
        function onkocutoff(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.Cutoff(INDEX) == 1
                app.CutoffLamp.Color = [0 1 0];
            else app.CutoffLamp.Color = [0.7,0.75,0.7];
            end
        end
        
        function onkoelongated(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.Elongated(INDEX) == 1
                app.ElongatedLamp.Color = [0 1 0];
            else app.ElongatedLamp.Color = [0.7,0.75,0.7];
            end
        end
        
        function onkoshattering(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.Shattering(INDEX) == 1
                app.ShatteringLamp.Color = [0 1 0];
            else app.ShatteringLamp.Color = [0.7,0.75,0.7];
            end
        end
        
        function onkominterestingflag(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.InterestingFlag(INDEX) == 1
                app.InterestingFlagLamp.Color = [0 1 0];
            else app.InterestingFlagLamp.Color = [0.7,0.75,0.7];
            end
        end
        
        function onkochain(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.ChainAgg(INDEX) == 1
                app.ChainAggLamp.Color = [0 1 0];
            else app.ChainAggLamp.Color = [0.7,0.75,0.7];
            end
        end
        
        function onkosublimating(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.Sublimating(INDEX) == 1
                app.SublimatingLamp.Color = [0 1 0];
            else app.SublimatingLamp.Color = [0.7,0.75,0.7];
            end
        end
        
        function check_confidencelevel(app)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            if TULOS.ConfidenceLevel(INDEX) == 0
                app.LowLamp.Color = [0.7,0.75,0.7];
                app.MediumLamp.Color = [0.7,0.75,0.7];
                app.HighLamp.Color = [0.7,0.75,0.7];
            elseif TULOS.ConfidenceLevel(INDEX) == 1
                app.LowLamp.Color = [0,1,0];
                app.MediumLamp.Color = [0.7,0.75,0.7];
                app.HighLamp.Color = [0.7,0.75,0.7];
            elseif TULOS.ConfidenceLevel(INDEX) == 2
                app.LowLamp.Color = [0.7,0.75,0.7];
                app.MediumLamp.Color = [0,1,0];
                app.HighLamp.Color = [0.7,0.75,0.7];
            elseif TULOS.ConfidenceLevel(INDEX) == 3
                app.LowLamp.Color = [0.7,0.75,0.7];
                app.MediumLamp.Color = [0.7,0.75,0.7];
                app.HighLamp.Color = [0,1,0];
            end
        end
        
        function savefilename = generate_savefilename(app)
            classifier_name = evalin('base', 'classifier_name');
            C1 = evalin('base', 'C1');
            today = datestr(date,'yyyymmdd'); % Today's date
            filename = strsplit(C1{1},'_');
            savefilename = [filename{1},'_',filename{2},'_Image_Classification_',classifier_name, '_', today,'.txt'];
        end
        
        function TULOS = reset_habitclass(app,TULOS,INDEX)
            % Find last habit (Droplet)
            ind = find(strcmp(TULOS.Properties.VariableNames,'Droplet'));
            TULOS(INDEX,2:ind) = array2table(0.*ones(1,ind-1));
        end
        
        function Show_image_time(app,INDEX,C1)
            time_string = strsplit(C1{INDEX},'_');
            time_string = datestr(datenum(time_string(3),'yyyymmddHHMMSS'),'yyyy-mm-dd HH:MM:SS');
            app.ImageTimeEditField.Value = time_string;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Give resolution for your previous screen:
            old_screen=[1680,1050];
            %Collect information about new screen:
            set(0,'units','pixels');
            Pix_SS = get(0,'screensize');
            %Assign coefficient of reduction/increase:
            k_hor=Pix_SS(3)/old_screen(1);
            k_vert=Pix_SS(4)/old_screen(2);
            %Get size of your original application UI:
            uisize = app.UIFigure.Position;
            screenWidth = uisize(3);
            screenHeight = uisize(4);
            left = uisize(1);
            bottom = uisize(2);
            %Assign new size for the width and height:
            width = screenWidth*k_hor;
            height = screenHeight*k_vert;
            %Assign coefficient to reduce font sizes:
            k_font=(k_hor+k_vert)/2;
            drawnow;
            
            if k_font<1 % Only scale down
                %Collect UI elements:
                comp=app.UIFigure.Children;
                %Change UI element size to new scaled size:
                app.UIFigure.Position = [left bottom width height];
                %Change locations, width, height, and font of each element:
                for i=1:numel(comp)
                    app.UIFigure.Children(i).Position(3)=comp(i).Position(3)*k_hor;
                    app.UIFigure.Children(i).Position(4)=comp(i).Position(4)*k_vert;
                    app.UIFigure.Children(i).Position(1)=comp(i).Position(1)*k_hor;
                    app.UIFigure.Children(i).Position(2)=comp(i).Position(2)*k_vert;
                    %Catch an error for possible UI elements not having FontSize
                    try
                        a{i} = comp(i).FontSize;
                    catch
                        warning('This object does not have font option.  Assigning a value of 0.');
                        a{i} = 0;
                    end
                    %CHange FontSize of all elements that actually have
                    %FontSize component
                    if a{i}~=0
                        app.UIFigure.Children(i).FontSize=comp(i).FontSize*k_font;
                    end
                end
                
                %Collect UI Selection Panel elements:
                comp=app.SelectionPanel.Children;
                %Change locations, width, height, and font of each element:
                for i=1:numel(comp)
                    app.SelectionPanel.Children(i).Position(3)=comp(i).Position(3)*k_hor;
                    app.SelectionPanel.Children(i).Position(4)=comp(i).Position(4)*k_vert;
                    app.SelectionPanel.Children(i).Position(1)=comp(i).Position(1)*k_hor;
                    app.SelectionPanel.Children(i).Position(2)=comp(i).Position(2)*k_vert;
                    %Catch an error for possible UI elements not having FontSize
                    try
                        a{i} = comp(i).FontSize;
                    catch
                        warning('This object does not have font option.  Assigning a value of 0.');
                        a{i} = 0;
                    end
                    %CHange FontSize of all elements that actually have
                    %FontSize component
                    if a{i}~=0
                        app.SelectionPanel.Children(i).FontSize=comp(i).FontSize*k_font;
                    end
                end
                
                %Collect UI Housekeeping Attributes Panel elements:
                comp=app.MultipleparticlesPanel.Children;
                app.MultipleparticlesPanel.FontSize = app.MultipleparticlesPanel.FontSize*k_font;
                %Change locations, width, height, and font of each element:
                for i=1:numel(comp)
                    app.MultipleparticlesPanel.Children(i).Position(3)=comp(i).Position(3)*k_hor;
                    app.MultipleparticlesPanel.Children(i).Position(4)=comp(i).Position(4)*k_vert;
                    app.MultipleparticlesPanel.Children(i).Position(1)=comp(i).Position(1)*k_hor;
                    app.MultipleparticlesPanel.Children(i).Position(2)=comp(i).Position(2)*k_vert;
                    %Catch an error for possible UI elements not having FontSize
                    try
                        a{i} = comp(i).FontSize;
                    catch
                        warning('This object does not have font option.  Assigning a value of 0.');
                        a{i} = 0;
                    end
                    %CHange FontSize of all elements that actually have
                    %FontSize component
                    if a{i}~=0
                        app.MultipleparticlesPanel.Children(i).FontSize=comp(i).FontSize*k_font;
                    end
                end
                
                %Collect UI Housekeeping Attributes Panel elements:
                comp=app.CutoffPanel.Children;
                app.CutoffPanel.FontSize = app.CutoffPanel.FontSize*k_font;
                %Change locations, width, height, and font of each element:
                for i=1:numel(comp)
                    app.CutoffPanel.Children(i).Position(3)=comp(i).Position(3)*k_hor;
                    app.CutoffPanel.Children(i).Position(4)=comp(i).Position(4)*k_vert;
                    app.CutoffPanel.Children(i).Position(1)=comp(i).Position(1)*k_hor;
                    app.CutoffPanel.Children(i).Position(2)=comp(i).Position(2)*k_vert;
                    %Catch an error for possible UI elements not having FontSize
                    try
                        a{i} = comp(i).FontSize;
                    catch
                        warning('This object does not have font option.  Assigning a value of 0.');
                        a{i} = 0;
                    end
                    %CHange FontSize of all elements that actually have
                    %FontSize component
                    if a{i}~=0
                        app.CutoffPanel.Children(i).FontSize=comp(i).FontSize*k_font;
                    end
                end
                
                %Collect UI Housekeeping Attributes Panel elements:
                comp=app.ElongatedPanel.Children;
                app.ElongatedPanel.FontSize = app.ElongatedPanel.FontSize*k_font;
                %Change locations, width, height, and font of each element:
                for i=1:numel(comp)
                    app.ElongatedPanel.Children(i).Position(3)=comp(i).Position(3)*k_hor;
                    app.ElongatedPanel.Children(i).Position(4)=comp(i).Position(4)*k_vert;
                    app.ElongatedPanel.Children(i).Position(1)=comp(i).Position(1)*k_hor;
                    app.ElongatedPanel.Children(i).Position(2)=comp(i).Position(2)*k_vert;
                    %Catch an error for possible UI elements not having FontSize
                    try
                        a{i} = comp(i).FontSize;
                    catch
                        warning('This object does not have font option.  Assigning a value of 0.');
                        a{i} = 0;
                    end
                    %CHange FontSize of all elements that actually have
                    %FontSize component
                    if a{i}~=0
                        app.ElongatedPanel.Children(i).FontSize=comp(i).FontSize*k_font;
                    end
                end
                
                %Collect UI Attributes Panel elements:
                comp=app.RimedPanel.Children;
                app.RimedPanel.FontSize = app.RimedPanel.FontSize*k_font;
                %Change locations, width, height, and font of each element:
                for i=1:numel(comp)
                    app.RimedPanel.Children(i).Position(3)=comp(i).Position(3)*k_hor;
                    app.RimedPanel.Children(i).Position(4)=comp(i).Position(4)*k_vert;
                    app.RimedPanel.Children(i).Position(1)=comp(i).Position(1)*k_hor;
                    app.RimedPanel.Children(i).Position(2)=comp(i).Position(2)*k_vert;
                    %Catch an error for possible UI elements not having FontSize
                    try
                        a{i} = comp(i).FontSize;
                    catch
                        warning('This object does not have font option.  Assigning a value of 0.');
                        a{i} = 0;
                    end
                    %CHange FontSize of all elements that actually have
                    %FontSize component
                    if a{i}~=0
                        app.RimedPanel.Children(i).FontSize=comp(i).FontSize*k_font;
                    end
                end
                
                %Collect UI Attributes Panel elements:
                comp=app.AggregatePanel.Children;
                app.AggregatePanel.FontSize = app.AggregatePanel.FontSize*k_font;
                %Change locations, width, height, and font of each element:
                for i=1:numel(comp)
                    app.AggregatePanel.Children(i).Position(3)=comp(i).Position(3)*k_hor;
                    app.AggregatePanel.Children(i).Position(4)=comp(i).Position(4)*k_vert;
                    app.AggregatePanel.Children(i).Position(1)=comp(i).Position(1)*k_hor;
                    app.AggregatePanel.Children(i).Position(2)=comp(i).Position(2)*k_vert;
                    %Catch an error for possible UI elements not having FontSize
                    try
                        a{i} = comp(i).FontSize;
                    catch
                        warning('This object does not have font option.  Assigning a value of 0.');
                        a{i} = 0;
                    end
                    %CHange FontSize of all elements that actually have
                    %FontSize component
                    if a{i}~=0
                        app.AggregatePanel.Children(i).FontSize=comp(i).FontSize*k_font;
                    end
                end
                
                %Collect UI Attributes Panel elements:
                comp=app.PristinePanel.Children;
                app.PristinePanel.FontSize = app.PristinePanel.FontSize*k_font;
                %Change locations, width, height, and font of each element:
                for i=1:numel(comp)
                    app.PristinePanel.Children(i).Position(3)=comp(i).Position(3)*k_hor;
                    app.PristinePanel.Children(i).Position(4)=comp(i).Position(4)*k_vert;
                    app.PristinePanel.Children(i).Position(1)=comp(i).Position(1)*k_hor;
                    app.PristinePanel.Children(i).Position(2)=comp(i).Position(2)*k_vert;
                    %Catch an error for possible UI elements not having FontSize
                    try
                        a{i} = comp(i).FontSize;
                    catch
                        warning('This object does not have font option.  Assigning a value of 0.');
                        a{i} = 0;
                    end
                    %CHange FontSize of all elements that actually have
                    %FontSize component
                    if a{i}~=0
                        app.PristinePanel.Children(i).FontSize=comp(i).FontSize*k_font;
                    end
                end
                
                %Collect UI Attributes Panel elements:
                comp=app.ChainAggregatePanel.Children;
                app.ChainAggregatePanel.FontSize = app.ChainAggregatePanel.FontSize*k_font;
                %Change locations, width, height, and font of each element:
                for i=1:numel(comp)
                    app.ChainAggregatePanel.Children(i).Position(3)=comp(i).Position(3)*k_hor;
                    app.ChainAggregatePanel.Children(i).Position(4)=comp(i).Position(4)*k_vert;
                    app.ChainAggregatePanel.Children(i).Position(1)=comp(i).Position(1)*k_hor;
                    app.ChainAggregatePanel.Children(i).Position(2)=comp(i).Position(2)*k_vert;
                    %Catch an error for possible UI elements not having FontSize
                    try
                        a{i} = comp(i).FontSize;
                    catch
                        warning('This object does not have font option.  Assigning a value of 0.');
                        a{i} = 0;
                    end
                    %CHange FontSize of all elements that actually have
                    %FontSize component
                    if a{i}~=0
                        app.ChainAggregatePanel.Children(i).FontSize=comp(i).FontSize*k_font;
                    end
                end
                
                %Collect UI Attributes Panel elements:
                comp=app.SublimatingPanel.Children;
                app.SublimatingPanel.FontSize = app.SublimatingPanel.FontSize*k_font;
                %Change locations, width, height, and font of each element:
                for i=1:numel(comp)
                    app.SublimatingPanel.Children(i).Position(3)=comp(i).Position(3)*k_hor;
                    app.SublimatingPanel.Children(i).Position(4)=comp(i).Position(4)*k_vert;
                    app.SublimatingPanel.Children(i).Position(1)=comp(i).Position(1)*k_hor;
                    app.SublimatingPanel.Children(i).Position(2)=comp(i).Position(2)*k_vert;
                    %Catch an error for possible UI elements not having FontSize
                    try
                        a{i} = comp(i).FontSize;
                    catch
                        warning('This object does not have font option.  Assigning a value of 0.');
                        a{i} = 0;
                    end
                    %CHange FontSize of all elements that actually have
                    %FontSize component
                    if a{i}~=0
                        app.SublimatingPanel.Children(i).FontSize=comp(i).FontSize*k_font;
                    end
                end
                
                % Change Confidence Level fonts
                app.LowButton.FontSize = app.LowButton.FontSize*k_font;
                app.MediumButton.FontSize = app.MediumButton.FontSize*k_font;
                app.HighButton.FontSize = app.HighButton.FontSize*k_font;
            end
        end

        % Button pushed function: ChooseFolderforPHIPSDataButton
        function ChooseFolderforPHIPSDataButtonPushed(app, event)
            
            folder = uigetdir;
            app.PHIPSDataFolderEditField.Value = folder;
            cd(folder)
            
            % Read image names in image folders
            C1_image_folder_name = dir('*C1'); C1_image_folder_name = {C1_image_folder_name(:).name}';
            C2_image_folder_name = dir('*C2'); C2_image_folder_name = {C2_image_folder_name(:).name}';
            % C1
            C1 = [];
            for i=1:size(C1_image_folder_name,1)
                cd(C1_image_folder_name{i})
                addpath([folder,filesep,C1_image_folder_name{i}])
                names = dir('*.png');
                names = names(arrayfun(@(x) x.name(1), names) ~= '.'); % Remove . entries
                names = struct2cell(names); names = names(1,:)';
                C1 = [C1; names];
                number_of_C1_images = size(C1,1);
                cd(folder)
            end
            % Split image name
            D = regexp(C1, '_', 'split');
            D = reshape([D{:,1}],[],length(D));
            % Image numbers
            C1_numbers = D(end-1,:)';
            C1_numbers = cellfun(@(x)str2double(x), C1_numbers); % From string cell to double
            
            % C2
            C2 = [];
            for i=1:size(C2_image_folder_name,1)
                cd(C2_image_folder_name{i})
                addpath([folder,filesep,C2_image_folder_name{i}])
                names = dir('*.png');
                names = names(arrayfun(@(x) x.name(1), names) ~= '.'); % Remove . entries
                names = struct2cell(names); names = names(1,:)';
                C2 = [C2; names];
                number_of_C2_images = size(C2,1);
                cd(folder)
            end
            % Split image name
            D = regexp(C2, '_', 'split');
            D = reshape([D{:,1}],[],length(D));
            % Image numbers
            C2_numbers = D(end-1,:)';
            C2_numbers = cellfun(@(x)str2double(x), C2_numbers); % From string cell to double
            
            % Add NaNs if image is missing
            if C1_numbers(end)>= C2_numbers(end)
                pituus = C1_numbers(end); % End index
            else pituus = C2_numbers(end);
            end
            % Start image numner
            start_index = C1_numbers(1);
            growing_index = [start_index:pituus]';
            temp = growing_index; temp(~ismember(growing_index,C1_numbers)) = NaN;
            temp2 = growing_index; temp2(~ismember(growing_index,C2_numbers)) = NaN;
            image_temp = repmat({''},pituus,1); image_temp(ismember(growing_index,C1_numbers)) = C1(ismember(C1_numbers,growing_index));
            image_temp2 = repmat({''},pituus,1); image_temp2(ismember(growing_index,C2_numbers)) = C2(ismember(C2_numbers,growing_index));
            C1 = image_temp;
            C2 = image_temp2;
            C1_numbers = temp;
            C2_numbers = temp2;
            
            %Import C1 and C2 to workspace
            assignin('base','C1',C1)
            assignin('base','C2',C2)
            assignin('base','C1_numbers',C1_numbers)
            assignin('base','C2_numbers',C2_numbers)
            
            % Load PHIPS image
            % C1
            I = imread(C1{1}); I = flip(I,2); % Mirror image
            imshow(I,'Parent',app.UIAxes); zoom(app.UIAxes,'on');
            % C2
            I = imread(C2{1}); I = flip(I,2); % Mirror image
            imshow(I,'Parent',app.UIAxes2); zoom(app.UIAxes2,'on');
            
            % Number of images
            app.EditField.Value = pituus;
            
            % Show current image number
            INDEX = 1;
            app.ImageEditField.Value = C1_numbers(INDEX);
            assignin('base','INDEX',INDEX)
            
            app.ClassifierEditField.Value = 'Enter Classifiers Name';
            app.ClassificationFileName.Value = 'Enter Classifiers Name, then load classification';
            
            % this could also be placed somewhere else in the initialization
            AllowmultipleHabits = 0;
            assignin('base','AllowmultipleHabits',AllowmultipleHabits)
            app.deselectingholdoffnextimageLabel.Visible = 0;
            
        end

        % Button pushed function: PlateButton
        function PlateButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Plate(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: YesButton_4
        function YesButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Rimed(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkorimed(app);
        end

        % Button pushed function: NoButton_4
        function NoButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Rimed(INDEX) = 0;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkorimed(app);
        end

        % Button pushed function: YesButton_5
        function YesButton_2Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Aggregate(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkoagregaatti(app);
        end

        % Button pushed function: NoButton_5
        function NoButton_2Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Aggregate(INDEX) = 0;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkoagregaatti(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
           AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0 % if AllowmultipleHabits is activated, this button does nothing
            % Get variables from workspace
            INDEX = evalin('base', 'INDEX');
            C1 = evalin('base', 'C1');
            C2 = evalin('base', 'C2');
            TULOS = evalin('base', 'TULOS');
            C1_numbers = evalin('base', 'C1_numbers');
                       
            
            % move to next image
            if INDEX<size(TULOS,1)
                INDEX = INDEX+1;
            end
            
            
            if TULOS.ConfidenceLevel(INDEX) == 0 || isnan(TULOS.ConfidenceLevel(INDEX))
                TULOS.ConfidenceLevel(INDEX) = 3;
            end
            % Skip particles
            skipped = 1;
            while skipped == 1
                skipped = 0;
                % check the "skip X" conditions
                if app.SkipNotClassifiedCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && isnan(TULOS.Plate(INDEX)) % skip every particle that is X
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
                % if a particle was skipped (i.e. index++) then test again for every X
                
                if app.SkipDropletCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Droplet(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
                if app.SkipIceCheckBox.Value == 1
                    % skip ice = only Droplets + Empty + not class
                    % if ice then drop == 0 + ice == 0
                    while INDEX<size(TULOS,1) && TULOS.Droplet(INDEX) == 0 && TULOS.Empty(INDEX) == 0
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
                if app.SkipIrregularCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Irregular(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
                if app.SkipShatteringCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Shattering(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
                if app.SkipEmptyCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Empty(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
                if app.SkipAggregateCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Aggregate(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
                if app.SkipMultipleCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Multiple(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
                if app.SkipRimedCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Rimed(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
                if app.SkipCutoffCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Cutoff(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
                if app.SkipPristineCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Pristine(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
                if app.SkipElongatedCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Elongated(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                if app.SkipSidePlaneCheckBox.Value == 1
                    while INDEX>1 && TULOS.SidePlane(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                if app.SkipBRosetCheckBox.Value == 1
                    while INDEX>1 && TULOS.Bullet_rosette(INDEX) == 1
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                if app.SkipNonpristineCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Pristine(INDEX) == 0
                        INDEX = INDEX+1; skipped = 1;
                    end
                end
                
            end
            
            assignin('base','INDEX',INDEX) %return the value to the app
            app.ImageEditField.Value = C1_numbers(INDEX);
            % Load PHIPS image
            % C1
            if ~isempty(C1{INDEX})
                I = imread(C1{INDEX}); I = flip(I,2); % Mirror image
                imshow(I,'Parent',app.UIAxes)
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto'; % Reset zoom
            end
            % C2
            if ~isempty(C2{INDEX})
                I = imread(C2{INDEX}); I = flip(I,2); % Mirror image
                imshow(I,'Parent',app.UIAxes2)
                app.UIAxes2.XLimMode = 'auto';
                app.UIAxes2.YLimMode = 'auto'; % Reset zoom
            end
            % Show classification
            String = show_classification(app);
            app.ClassifiedasEditField.Value = String;
            % Show attributes
            onkoagregaatti(app);
            onkorimed(app);
            onkopristine(app);
            onkomultiple(app);
            onkocutoff(app);
            onkoelongated(app);
            onkochain(app);
            onkosublimating(app);
            onkoshattering(app);
            onkominterestingflag(app);
            % Show image time
            Show_image_time(app,INDEX,C1);
            
            
            % Import TULOS to workspace
            check_confidencelevel(app);
            assignin('base','TULOS',TULOS)
            check_confidencelevel(app);
        end
            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            C1 = evalin('base', 'C1');
            C2 = evalin('base', 'C2');
            TULOS = evalin('base', 'TULOS');
            C1_numbers = evalin('base', 'C1_numbers');
       
            
            % move to prev. image
            if INDEX>1
                INDEX = INDEX-1;
            end
            
            % Skip particles
            skipped = 1;
            while skipped == 1
                skipped = 0;
                % check the "skip X" conditions
                if app.SkipNotClassifiedCheckBox.Value == 1
                    while INDEX>1 && isnan(TULOS.Plate(INDEX)) % skip every particle that is X
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                
                % if a particle was skipped (i.e. index--) then test again for every X
                
                if app.SkipDropletCheckBox.Value == 1
                    while INDEX>1 && TULOS.Droplet(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                
                if app.SkipIceCheckBox.Value == 1
                    % skip ice = only Droplets - Empty - not class
                    % if ice then drop == 0 - ice == 0
                    while INDEX>1 && TULOS.Droplet(INDEX) == 0 && TULOS.Empty(INDEX) == 0
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                
                if app.SkipIrregularCheckBox.Value == 1
                    while INDEX>1 && TULOS.Irregular(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                
                if app.SkipShatteringCheckBox.Value == 1
                    while INDEX>1 && TULOS.Shattering(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                
                if app.SkipEmptyCheckBox.Value == 1
                    while INDEX>1 && TULOS.Empty(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                
                if app.SkipAggregateCheckBox.Value == 1
                    while INDEX>1 && TULOS.Aggregate(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                
                if app.SkipMultipleCheckBox.Value == 1
                    while INDEX>1 && TULOS.Multiple(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                
                if app.SkipRimedCheckBox.Value == 1
                    while INDEX>1 && TULOS.Rimed(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                
                if app.SkipCutoffCheckBox.Value == 1
                    while INDEX>1 && TULOS.Cutoff(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                
                if app.SkipPristineCheckBox.Value == 1
                    while INDEX>1 && TULOS.Pristine(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                
                if app.SkipElongatedCheckBox.Value == 1
                    while INDEX>1 && TULOS.Elongated(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                if app.SkipSidePlaneCheckBox.Value == 1
                    while INDEX>1 && TULOS.SidePlane(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                if app.SkipBRosetCheckBox.Value == 1
                    while INDEX>1 && TULOS.Bullet_rosette(INDEX) == 1
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
                if app.SkipNonpristineCheckBox.Value == 1
                    while INDEX<size(TULOS,1) && TULOS.Pristine(INDEX) == 0
                        INDEX = INDEX-1; skipped = 1;
                    end
                end
            end
            
            assignin('base','INDEX',INDEX) %return the value to the app
            app.ImageEditField.Value = C1_numbers(INDEX);
            % Load PHIPS image
            % C1
            if ~isempty(C1{INDEX})
                I = imread(C1{INDEX}); I = flip(I,2); % Mirror image
                imshow(I,'Parent',app.UIAxes)
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto'; % Reset zoom
            end
            % C2
            if ~isempty(C2{INDEX})
                I = imread(C2{INDEX}); I = flip(I,2); % Mirror image
                imshow(I,'Parent',app.UIAxes2)
                app.UIAxes2.XLimMode = 'auto';
                app.UIAxes2.YLimMode = 'auto'; % Reset zoom
            end
            % Show classification
            String = show_classification(app);
            app.ClassifiedasEditField.Value = String;
            % Show attributes
            onkoagregaatti(app);
            onkorimed(app);
            onkopristine(app);
            onkomultiple(app);
            onkocutoff(app);
            onkoelongated(app);
            onkochain(app);
            onkosublimating(app);
            % Show image time
            Show_image_time(app,INDEX,C1);
            
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            check_confidencelevel(app)
        end

        % Button pushed function: ColumnButton
        function ColumnButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Column(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: FrozenDropletButton
        function FrozenDropletButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Frozen_droplet(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: BulletRosetteButton
        function BulletRosetteButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Bullet_rosette(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: NeedleButton
        function NeedleButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Needle(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: IrregularButton
        function IrregularButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Irregular(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: HollowColumnButton
        function HollowColumnButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Hollow_Column(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: SkeletonPlateButton
        function SkeletonPlateButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Skeleton_Plate(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: SectoredPlateButton
        function SectoredPlateButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Sectored_Plate(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: SheathButton
        function SheathButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Sheath(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: DropletButton
        function DropletButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Droplet(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            TULOS = evalin('base', 'TULOS');
            savefilename = generate_savefilename(app);
            writetable(TULOS,savefilename)
        end

        % Button pushed function: SidePlaneButton
        function SidePlaneButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.SidePlane(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: CappedColumnButton
        function CappedColumnButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.CappedColumn(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Value changed function: ImageEditField
        function ImageEditFieldValueChanged(app, event)
            C1_numbers = evalin('base','C1_numbers');
            image_number = app.ImageEditField.Value;
            INDEX = find(C1_numbers==image_number); % get index
            if ~isempty(INDEX)
                assignin('base','INDEX',INDEX)
                C1 = evalin('base', 'C1');
                C2 = evalin('base', 'C2');
                % Load PHIPS image
                % C1
                imshow(C1{INDEX},'Parent',app.UIAxes)
                % C2
                imshow(C2{INDEX},'Parent',app.UIAxes2)
                % Show classification
                String = show_classification(app);
                app.ClassifiedasEditField.Value = String;
                % Check attributes
                onkoagregaatti(app);
                onkorimed(app);
                onkopristine(app);
                onkomultiple(app);
                onkocutoff(app);
                onkoelongated(app);
                onkochain(app);
                onkosublimating(app);
            end
        end

        % Button pushed function: DendriteButton
        function DendriteButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Dendrite(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: YesButton_6
        function YesButton_3Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Pristine(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkopristine(app);
        end

        % Button pushed function: NoButton_6
        function NoButton_3Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Pristine(INDEX) = 0;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkopristine(app);
        end

        % Button pushed function: GraupelButton
        function GraupelButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0
                % Set all NaN-values to 0
                TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                % Reset any previous habit selection
                TULOS = reset_habitclass(app,TULOS,INDEX);
            end
            TULOS.Graupel(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: NotClassifiedButton
        function NotClassifiedButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Set all habits to NaN
            ind = find(strcmp(TULOS.Properties.VariableNames,'Droplet'));
            TULOS(INDEX,2:ind) = array2table(NaN.*ones(1,ind-1));
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Callback function
        function YesButton_4Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Set all NaN-values to 0
            TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
            TULOS.Shattering(INDEX) = 1;
            % Set all habits to NaN
            ind = find(strcmp(TULOS.Properties.VariableNames,'Droplet'));
            TULOS(INDEX,2:ind) = array2table(NaN.*ones(1,ind-1));
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkoshattering(app);
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Callback function
        function NoButton_4Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Shattering(INDEX) = 0;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkoshattering(app);
        end

        % Button pushed function: YesButton
        function YesButton_5Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Multiple(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkomultiple(app);
        end

        % Button pushed function: NoButton
        function NoButton_5Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Multiple(INDEX) = 0;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkomultiple(app);
        end

        % Button pushed function: YesButton_2
        function YesButton_6Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Cutoff(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkocutoff(app);
        end

        % Button pushed function: NoButton_2
        function NoButton_6Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Cutoff(INDEX) = 0;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkocutoff(app);
        end

        % Button pushed function: YesButton_3
        function YesButton_7Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Elongated(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkoelongated(app);
        end

        % Button pushed function: NoButton_3
        function NoButton_7Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Elongated(INDEX) = 0;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkoelongated(app);
        end

        % Button pushed function: EmptyButton
        function EmptyButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Set all NaN-values to 0
            TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
            TULOS.Empty(INDEX) = 1;
            % Set all habits to NaN
            ind = find(strcmp(TULOS.Properties.VariableNames,'Droplet'));
            TULOS(INDEX,2:ind) = array2table(NaN.*ones(1,ind-1));
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end

        % Button pushed function: YesButton_7
        function YesButton_8Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.ChainAgg(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkochain(app);
        end

        % Button pushed function: NoButton_7
        function NoButton_8Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.ChainAgg(INDEX) = 0;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkochain(app);
        end

        % Callback function
        function ShatteringButtonPushed(app, event)
            %             INDEX = evalin('base', 'INDEX');
            %             TULOS = evalin('base', 'TULOS');
            %             % Set all NaN-values to 0
            %             TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
            %             TULOS.Shattering(INDEX) = 1;
            %             % Set all habits to NaN
            %             ind = find(strcmp(TULOS.Properties.VariableNames,'Droplet'));
            %             TULOS(INDEX,2:ind) = array2table(NaN.*ones(1,ind-1));
            %             % Import TULOS to workspace
            %             assignin('base','TULOS',TULOS)
            %             % Go to the next image
            %             NextButtonPushed(app,event)
            
        end

        % Button pushed function: YesButton_8
        function YesButton_9Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Sublimating(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkosublimating(app);
        end

        % Button pushed function: NoButton_8
        function NoButton_9Pushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Sublimating(INDEX) = 0;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkosublimating(app);
        end

        % Button pushed function: LowButton
        function LowButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.ConfidenceLevel(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            check_confidencelevel(app)
        end

        % Button pushed function: MediumButton
        function MediumButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.ConfidenceLevel(INDEX) = 2;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            check_confidencelevel(app)
        end

        % Button pushed function: HighButton
        function HighButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.ConfidenceLevel(INDEX) = 3;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            check_confidencelevel(app)
        end

        % Value changing function: ClassifierEditField
        function ClassifierEditFieldValueChanging(app, event)
            classifier_name = event.Value;
            assignin('base', 'classifier_name', classifier_name)
            
        end

        % Button pushed function: LoadExistingClassificationButton
        function LoadExistingClassificationButtonPushed(app, event)
            [class_file_name,class_file_path] = uigetfile('*.txt');
            
            C1 = evalin('base','C1');
            C1_numbers = evalin('base','C1_numbers');
            C2_numbers = evalin('base','C2_numbers');
            % Add NaNs if image is missing
            if C1_numbers(end)>= C2_numbers(end)
                pituus = C1_numbers(end); % End index
            else pituus = C2_numbers(end);
            end
            
            start_index = C1_numbers(1);
            growing_index = [start_index:pituus]';
            
            app.ClassificationFileName.Value = class_file_name;
            
            % Check if image classification file exists
            %          [fileexists, filename,classifier] = onkoolemassa(app);
            
            TULOS = readtable([class_file_path, filesep, class_file_name]);
            
            % if there exists an old file with an older version, add
            % cols to the table
            if size(TULOS,2) == 20 % old format without the new diagnostic variables
                Shattering = ones(1,pituus-start_index+1)'+NaN;
                Multiple = ones(1,pituus-start_index+1)'+NaN;
                Cutoff = ones(1,pituus-start_index+1)'+NaN;
                Elongated = ones(1,pituus-start_index+1)'+NaN;
                ChainAgg = ones(1,pituus-start_index+1)'+NaN;
                Sublimating = ones(1,pituus-start_index+1)'+NaN;
                Empty = ones(1,pituus-start_index+1)'+NaN;
                InterestingFlag = ones(1,pituus-start_index+1)'+NaN;
                
                temp = table(Shattering,Multiple,Cutoff,Elongated,ChainAgg,Sublimating,Empty,InterestingFlag);
                
                TULOS = [TULOS,temp];
            elseif size(TULOS,2) == 26 % without sublimating
                Sublimating = ones(1,pituus-start_index+1)'+NaN;
                
                temp = table(Sublimating);
                
                TULOS = [TULOS(:,1:end-1),temp, TULOS(:,end)];
            end
            
            % If column name small irregular exists, change this to irregular
            Exist_Column = sum(ismember(TULOS.Properties.VariableNames,'Small_irregular'));
            if Exist_Column == 1
                TULOS.Irregular(TULOS.Small_irregular==1) = 1;
                TULOS.Small_irregular = [];
            end
            
            % If column name small unidentified exists, change this to irregular
            Exist_Column = sum(ismember(TULOS.Properties.VariableNames,'Unidentified'));
            if Exist_Column == 1
                TULOS.Irregular(TULOS.Unidentified==1) = 1;
                TULOS.Unidentified = [];
            end
            
            % If column name "ChainAgg" does not exist, create this
            Exist_Column = sum(ismember(TULOS.Properties.VariableNames,'ChainAgg'));
            if Exist_Column == 0
                ChainAgg = ones(1,size(TULOS,1))'+NaN;
                TULOS = [TULOS,table(ChainAgg)];
            end
            
            % If column name "ConfidenceLevel" does not exist, create this
            Exist_Column = sum(ismember(TULOS.Properties.VariableNames,'ConfidenceLevel'));
            if Exist_Column == 0
                ConfidenceLevel = ones(1,size(TULOS,1))'+NaN;
                TULOS = [TULOS,table(ConfidenceLevel)];
            end
            
            assignin('base','TULOS',TULOS)
            INDEX = 1; assignin('base','INDEX',INDEX)
            
            % Jump to where last classification ended
            ind = find(TULOS.Plate==0 | TULOS.Plate==1);
            ind = ind(end);
            INDEX = ind; assignin('base','INDEX',INDEX)
            app.ImageEditField.Value = TULOS.Image_number(INDEX);
            ImageEditFieldValueChanged(app, event);
            
            
            % Show classification
            String = show_classification(app);
            app.ClassifiedasEditField.Value = String;
            % Check aggregate and rimed
            onkoagregaatti(app);
            onkorimed(app);
            onkopristine(app);
            onkomultiple(app);
            onkocutoff(app);
            onkoelongated(app);
            onkochain(app);
            % Show image time
            Show_image_time(app,INDEX,C1);
            
            savefilename = generate_savefilename(app);
            app.ClassificationFileName.Value = savefilename;
        end

        % Button pushed function: MakeNewClassificationButton
        function MakeNewClassificationButtonPushed(app, event)
            
            C1 = evalin('base','C1');
            C1_numbers = evalin('base','C1_numbers');
            C2_numbers = evalin('base','C2_numbers');
            % Add NaNs if image is missing
            if C1_numbers(end)>= C2_numbers(end)
                pituus = C1_numbers(end); % End index
            else pituus = C2_numbers(end);
            end
            
            start_index = C1_numbers(1);
            growing_index = [start_index:pituus]';
            
            Image_number = growing_index;
            Plate = ones(1,pituus-start_index+1)'.*NaN;
            Skeleton_Plate = ones(1,pituus-start_index+1)'.*NaN;
            Sectored_Plate = ones(1,pituus-start_index+1)'.*NaN;
            Column = ones(1,pituus-start_index+1)'.*NaN;
            Hollow_Column = ones(1,pituus-start_index+1)'.*NaN;
            Sheath = ones(1,pituus-start_index+1)'.*NaN;
            Dendrite = ones(1,pituus-start_index+1)'.*NaN;
            Needle = ones(1,pituus-start_index+1)'.*NaN;
            SidePlane = ones(1,pituus-start_index+1)'.*NaN;
            CappedColumn = ones(1,pituus-start_index+1)'.*NaN;
            Frozen_droplet = ones(1,pituus-start_index+1)'.*NaN;
            Bullet_rosette = ones(1,pituus-start_index+1)'.*NaN;
            Irregular = ones(1,pituus-start_index+1)'.*NaN;
            Droplet = ones(1,pituus-start_index+1)'.*NaN;
            Graupel = ones(1,pituus-start_index+1)'.*NaN;
            Aggregate = ones(1,pituus-start_index+1)'+NaN;
            Rimed = ones(1,pituus-start_index+1)'+NaN;
            Pristine = ones(1,pituus-start_index+1)'+NaN;
            Shattering = ones(1,pituus-start_index+1)'+NaN;
            Multiple = ones(1,pituus-start_index+1)'+NaN;
            Cutoff = ones(1,pituus-start_index+1)'+NaN;
            Elongated = ones(1,pituus-start_index+1)'+NaN;
            ChainAgg = ones(1,pituus-start_index+1)'+NaN;
            Sublimating = ones(1,pituus-start_index+1)'+NaN;
            Empty = ones(1,pituus-start_index+1)'+NaN;
            InterestingFlag = ones(1,pituus-start_index+1)'+NaN;
            ConfidenceLevel = ones(1,pituus-start_index+1)'+NaN;
            TULOS = table(Image_number,Plate,Skeleton_Plate,Sectored_Plate,SidePlane,Dendrite,Column,Hollow_Column,...
                Sheath,CappedColumn,Needle,Frozen_droplet,Bullet_rosette,Graupel,Irregular,Droplet,...
                Aggregate,Rimed,Pristine,Shattering,Multiple,Cutoff,Elongated,ChainAgg,Sublimating,Empty,ConfidenceLevel, InterestingFlag);
            
            %Import table to workspace
            assignin('base','TULOS',TULOS)
            
            % Show classification
            String = show_classification(app);
            app.ClassifiedasEditField.Value = String;
            % Check aggregate and rimed
            onkoagregaatti(app);
            onkorimed(app);
            onkopristine(app);
            onkomultiple(app);
            onkocutoff(app);
            onkoelongated(app);
            onkoshattering(app);
            onkochain(app);
            onkominterestingflag(app);
            % Show image time
            Show_image_time(app,1,C1);
            
            savefilename = generate_savefilename(app);
            app.ClassificationFileName.Value = savefilename;
        end

        % Button pushed function: YesButton_9
        function YesButton_9Pushed2(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Shattering(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkoshattering(app);
        end

        % Button pushed function: NoButton_9
        function NoButton_9Pushed2(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.Shattering(INDEX) = 0;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkoshattering(app);
        end

        % Button pushed function: HoldAllowmultipleHabitsButton
        function HoldAllowmultipleHabitsButtonPushed(app, event)
            AllowmultipleHabits = evalin('base', 'AllowmultipleHabits');
            if AllowmultipleHabits == 0 %switch on
                % fill in the whole row with zeros (else you will have lots of NaNs)
                INDEX = evalin('base', 'INDEX');
                TULOS = evalin('base', 'TULOS');
                if isnan(sum(table2array(TULOS(INDEX,2:end))))
                    TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
                    assignin('base','TULOS',TULOS)
                end
                AllowmultipleHabits = 1;
                app.HoldAllowmultipleHabitsButton.BackgroundColor = [0.8 0.8 0.8];
                app.deselectingholdoffnextimageLabel.Visible = 1;
                app.NextButton.Enable = 0;
                assignin('base','AllowmultipleHabits',AllowmultipleHabits)
            elseif AllowmultipleHabits == 1 %switch off
                AllowmultipleHabits = 0;
                app.HoldAllowmultipleHabitsButton.BackgroundColor = [0.94 0.94 0.94];
                app.deselectingholdoffnextimageLabel.Visible = 0;
                app.NextButton.Enable = 1;
                assignin('base','AllowmultipleHabits',AllowmultipleHabits)
                NextButtonPushed(app,event) % next particle
            end         
            
        end

        % Button pushed function: FlagasinterestingButton
        function FlagasinterestingButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Modify TULOS
            TULOS.InterestingFlag(INDEX) = 1;
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Change lamp color
            onkominterestingflag(app);
        end

        % Button pushed function: IrregularPotentialShatteringButton
        function IrregularPotentialShatteringButtonPushed(app, event)
            INDEX = evalin('base', 'INDEX');
            TULOS = evalin('base', 'TULOS');
            % Set all NaN-values to 0
            TULOS(INDEX,isnan(table2array(TULOS(INDEX,:)))) = table(0);
            TULOS.Shattering(INDEX) = 1;
            TULOS.Irregular(INDEX) = 1;
            onkoshattering(app); % light the lamp
            % Import TULOS to workspace
            assignin('base','TULOS',TULOS)
            % Go to the next image
            NextButtonPushed(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.UIAxes = uiaxes(app.MainTab);
            title(app.UIAxes, 'C1')
            app.UIAxes2 = uiaxes(app.MainTab);
            title(app.UIAxes2, 'C2')
            app.ChooseFolderforPHIPSDataButton = uibutton(app.MainTab, 'push');
            app.ChooseFolderforPHIPSDataButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderforPHIPSDataButtonPushed, true);
            app.ChooseFolderforPHIPSDataButton.Text = '1: Choose Folder for PHIPS Data';
            app.Label = uilabel(app.MainTab);
            app.Label.Text = '';
            app.SaveButton = uibutton(app.MainTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HousekeepingAttributesPanel = uipanel(app.MainTab);
            app.HousekeepingAttributesPanel.Title = 'Housekeeping Attributes';
            app.MultipleparticlesPanel = uipanel(app.HousekeepingAttributesPanel);
            app.MultipleparticlesPanel.Title = 'Multiple particles';
            app.YesButton = uibutton(app.MultipleparticlesPanel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButton_5Pushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.MultipleparticlesPanel, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButton_5Pushed, true);
            app.NoButton.Text = 'No';
            app.CutoffPanel = uipanel(app.HousekeepingAttributesPanel);
            app.CutoffPanel.Title = 'Cut off?';
            app.YesButton_2 = uibutton(app.CutoffPanel, 'push');
            app.YesButton_2.ButtonPushedFcn = createCallbackFcn(app, @YesButton_6Pushed, true);
            app.YesButton_2.Text = 'Yes';
            app.NoButton_2 = uibutton(app.CutoffPanel, 'push');
            app.NoButton_2.ButtonPushedFcn = createCallbackFcn(app, @NoButton_6Pushed, true);
            app.NoButton_2.Text = 'No';
            app.ElongatedPanel = uipanel(app.HousekeepingAttributesPanel);
            app.ElongatedPanel.Title = 'Elongated?';
            app.YesButton_3 = uibutton(app.ElongatedPanel, 'push');
            app.YesButton_3.ButtonPushedFcn = createCallbackFcn(app, @YesButton_7Pushed, true);
            app.YesButton_3.Text = 'Yes';
            app.NoButton_3 = uibutton(app.ElongatedPanel, 'push');
            app.NoButton_3.ButtonPushedFcn = createCallbackFcn(app, @NoButton_7Pushed, true);
            app.NoButton_3.Text = 'No';
            app.ShatteringPanel = uipanel(app.HousekeepingAttributesPanel);
            app.ShatteringPanel.Title = 'Shattering?';
            app.YesButton_9 = uibutton(app.ShatteringPanel, 'push');
            app.YesButton_9.ButtonPushedFcn = createCallbackFcn(app, @YesButton_9Pushed2, true);
            app.YesButton_9.Text = 'Yes';
            app.NoButton_9 = uibutton(app.ShatteringPanel, 'push');
            app.NoButton_9.ButtonPushedFcn = createCallbackFcn(app, @NoButton_9Pushed2, true);
            app.NoButton_9.Text = 'No';
            app.AttributesPanel = uipanel(app.MainTab);
            app.AttributesPanel.Title = 'Attributes';
            app.RimedPanel = uipanel(app.AttributesPanel);
            app.RimedPanel.Title = 'Rimed?';
            app.YesButton_4 = uibutton(app.RimedPanel, 'push');
            app.YesButton_4.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton_4.Text = 'Yes';
            app.NoButton_4 = uibutton(app.RimedPanel, 'push');
            app.NoButton_4.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton_4.Text = 'No';
            app.AggregatePanel = uipanel(app.AttributesPanel);
            app.AggregatePanel.Title = 'Aggregate?';
            app.YesButton_5 = uibutton(app.AggregatePanel, 'push');
            app.YesButton_5.ButtonPushedFcn = createCallbackFcn(app, @YesButton_2Pushed, true);
            app.YesButton_5.Text = 'Yes';
            app.NoButton_5 = uibutton(app.AggregatePanel, 'push');
            app.NoButton_5.ButtonPushedFcn = createCallbackFcn(app, @NoButton_2Pushed, true);
            app.NoButton_5.Text = 'No';
            app.PristinePanel = uipanel(app.AttributesPanel);
            app.PristinePanel.Title = 'Pristine?';
            app.YesButton_6 = uibutton(app.PristinePanel, 'push');
            app.YesButton_6.ButtonPushedFcn = createCallbackFcn(app, @YesButton_3Pushed, true);
            app.YesButton_6.Text = 'Yes';
            app.NoButton_6 = uibutton(app.PristinePanel, 'push');
            app.NoButton_6.ButtonPushedFcn = createCallbackFcn(app, @NoButton_3Pushed, true);
            app.NoButton_6.Text = 'No';
            app.ChainAggregatePanel = uipanel(app.AttributesPanel);
            app.ChainAggregatePanel.Title = 'Chain Aggregate?';
            app.YesButton_7 = uibutton(app.ChainAggregatePanel, 'push');
            app.YesButton_7.ButtonPushedFcn = createCallbackFcn(app, @YesButton_8Pushed, true);
            app.YesButton_7.Text = 'Yes';
            app.NoButton_7 = uibutton(app.ChainAggregatePanel, 'push');
            app.NoButton_7.ButtonPushedFcn = createCallbackFcn(app, @NoButton_8Pushed, true);
            app.NoButton_7.Text = 'No';
            app.SublimatingPanel = uipanel(app.AttributesPanel);
            app.SublimatingPanel.Title = 'Sublimating?';
            app.YesButton_8 = uibutton(app.SublimatingPanel, 'push');
            app.YesButton_8.ButtonPushedFcn = createCallbackFcn(app, @YesButton_9Pushed, true);
            app.YesButton_8.Text = 'Yes';
            app.NoButton_8 = uibutton(app.SublimatingPanel, 'push');
            app.NoButton_8.ButtonPushedFcn = createCallbackFcn(app, @NoButton_9Pushed, true);
            app.NoButton_8.Text = 'No';
            app.ConfidenceLevelPanel = uipanel(app.MainTab);
            app.ConfidenceLevelPanel.Title = 'Confidence Level';
            app.LowButton = uibutton(app.ConfidenceLevelPanel, 'push');
            app.LowButton.ButtonPushedFcn = createCallbackFcn(app, @LowButtonPushed, true);
            app.LowButton.Text = 'Low';
            app.MediumButton = uibutton(app.ConfidenceLevelPanel, 'push');
            app.MediumButton.ButtonPushedFcn = createCallbackFcn(app, @MediumButtonPushed, true);
            app.MediumButton.Text = 'Medium';
            app.HighButton = uibutton(app.ConfidenceLevelPanel, 'push');
            app.HighButton.ButtonPushedFcn = createCallbackFcn(app, @HighButtonPushed, true);
            app.HighButton.Text = 'High';
            app.MakeNewClassificationButton = uibutton(app.MainTab, 'push');
            app.MakeNewClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @MakeNewClassificationButtonPushed, true);
            app.MakeNewClassificationButton.Text = '3: Make New Classification';
            app.ClassificationFileName = uieditfield(app.MainTab, 'text');
            app.ClassificationFileName.Editable = 'off';
            app.ClassificationFileName.Value = 'load PhipsData first';
            app.text_or = uilabel(app.MainTab);
            app.text_or.Text = 'or';
            app.LoadExistingClassificationButton = uibutton(app.MainTab, 'push');
            app.LoadExistingClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingClassificationButtonPushed, true);
            app.LoadExistingClassificationButton.Text = '3: Load Existing Classification';
            app.SkipSidePlaneCheckBox = uicheckbox(app.MainTab);
            app.SkipSidePlaneCheckBox.Text = 'Skip Side Plane';
            app.SkipBRosetCheckBox = uicheckbox(app.MainTab);
            app.SkipBRosetCheckBox.Text = 'Skip B. Roset.';
            app.SkipNonpristineCheckBox = uicheckbox(app.MainTab);
            app.SkipNonpristineCheckBox.Text = 'Skip Non-pristine';
            app.SelectionPanel = uipanel(app.MainTab);
            app.SelectionPanel.Title = 'Selection';
            app.PlateButton = uibutton(app.SelectionPanel, 'push');
            app.PlateButton.ButtonPushedFcn = createCallbackFcn(app, @PlateButtonPushed, true);
            app.PlateButton.Text = 'Plate';
            app.ColumnButton = uibutton(app.SelectionPanel, 'push');
            app.ColumnButton.ButtonPushedFcn = createCallbackFcn(app, @ColumnButtonPushed, true);
            app.ColumnButton.Text = 'Column';
            app.FrozenDropletButton = uibutton(app.SelectionPanel, 'push');
            app.FrozenDropletButton.ButtonPushedFcn = createCallbackFcn(app, @FrozenDropletButtonPushed, true);
            app.FrozenDropletButton.Text = 'Frozen Droplet';
            app.BulletRosetteButton = uibutton(app.SelectionPanel, 'push');
            app.BulletRosetteButton.ButtonPushedFcn = createCallbackFcn(app, @BulletRosetteButtonPushed, true);
            app.BulletRosetteButton.Text = 'Bullet Rosette';
            app.NeedleButton = uibutton(app.SelectionPanel, 'push');
            app.NeedleButton.ButtonPushedFcn = createCallbackFcn(app, @NeedleButtonPushed, true);
            app.NeedleButton.Text = 'Needle';
            app.IrregularButton = uibutton(app.SelectionPanel, 'push');
            app.IrregularButton.ButtonPushedFcn = createCallbackFcn(app, @IrregularButtonPushed, true);
            app.IrregularButton.Text = 'Irregular';
            app.HollowColumnButton = uibutton(app.SelectionPanel, 'push');
            app.HollowColumnButton.ButtonPushedFcn = createCallbackFcn(app, @HollowColumnButtonPushed, true);
            app.HollowColumnButton.Text = 'Hollow Column';
            app.SkeletonPlateButton = uibutton(app.SelectionPanel, 'push');
            app.SkeletonPlateButton.ButtonPushedFcn = createCallbackFcn(app, @SkeletonPlateButtonPushed, true);
            app.SkeletonPlateButton.Text = 'Skeleton Plate';
            app.SectoredPlateButton = uibutton(app.SelectionPanel, 'push');
            app.SectoredPlateButton.ButtonPushedFcn = createCallbackFcn(app, @SectoredPlateButtonPushed, true);
            app.SectoredPlateButton.Text = 'Sectored Plate';
            app.SheathButton = uibutton(app.SelectionPanel, 'push');
            app.SheathButton.ButtonPushedFcn = createCallbackFcn(app, @SheathButtonPushed, true);
            app.SheathButton.Text = 'Sheath';
            app.DropletButton = uibutton(app.SelectionPanel, 'push');
            app.DropletButton.ButtonPushedFcn = createCallbackFcn(app, @DropletButtonPushed, true);
            app.DropletButton.Text = 'Droplet';
            app.SidePlaneButton = uibutton(app.SelectionPanel, 'push');
            app.SidePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @SidePlaneButtonPushed, true);
            app.SidePlaneButton.Text = 'Side Plane';
            app.CappedColumnButton = uibutton(app.SelectionPanel, 'push');
            app.CappedColumnButton.ButtonPushedFcn = createCallbackFcn(app, @CappedColumnButtonPushed, true);
            app.CappedColumnButton.Text = 'Capped Column';
            app.DendriteButton = uibutton(app.SelectionPanel, 'push');
            app.DendriteButton.ButtonPushedFcn = createCallbackFcn(app, @DendriteButtonPushed, true);
            app.DendriteButton.Text = 'Dendrite';
            app.GraupelButton = uibutton(app.SelectionPanel, 'push');
            app.GraupelButton.ButtonPushedFcn = createCallbackFcn(app, @GraupelButtonPushed, true);
            app.GraupelButton.Text = 'Graupel';
            app.HabitCategoryLabel = uilabel(app.SelectionPanel);
            app.HabitCategoryLabel.Text = 'Habit Category';
            app.HoldAllowmultipleHabitsButton = uibutton(app.SelectionPanel, 'push');
            app.HoldAllowmultipleHabitsButton.ButtonPushedFcn = createCallbackFcn(app, @HoldAllowmultipleHabitsButtonPushed, true);
            app.HoldAllowmultipleHabitsButton.Text = 'Hold: Allow multiple Habits';
            app.NextButton = uibutton(app.MainTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.MainTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.EmptyButton = uibutton(app.MainTab, 'push');
            app.EmptyButton.ButtonPushedFcn = createCallbackFcn(app, @EmptyButtonPushed, true);
            app.EmptyButton.Text = 'Empty';
            app.NotClassifiedButton = uibutton(app.MainTab, 'push');
            app.NotClassifiedButton.ButtonPushedFcn = createCallbackFcn(app, @NotClassifiedButtonPushed, true);
            app.NotClassifiedButton.Text = 'Not Classified';
            app.ClassifierEditFieldLabel = uilabel(app.MainTab);
            app.ClassifierEditFieldLabel.Text = '2: Classifier';
            app.ClassifierEditField = uieditfield(app.MainTab, 'text');
            app.ClassifierEditField.ValueChangingFcn = createCallbackFcn(app, @ClassifierEditFieldValueChanging, true);
            app.ClassifierEditField.Value = 'load PhipsData first';
            app.PHIPSDataFolderEditFieldLabel = uilabel(app.MainTab);
            app.PHIPSDataFolderEditFieldLabel.Text = 'PHIPS Data Folder';
            app.PHIPSDataFolderEditField = uieditfield(app.MainTab, 'text');
            app.ClassifiedasEditFieldLabel = uilabel(app.MainTab);
            app.ClassifiedasEditFieldLabel.Text = 'Classified as:';
            app.ClassifiedasEditField = uieditfield(app.MainTab, 'text');
            app.SkipNotClassifiedCheckBox = uicheckbox(app.MainTab);
            app.SkipNotClassifiedCheckBox.Text = 'Skip Not Classified';
            app.SkipDropletCheckBox = uicheckbox(app.MainTab);
            app.SkipDropletCheckBox.Text = 'Skip Droplet';
            app.SkipIceCheckBox = uicheckbox(app.MainTab);
            app.SkipIceCheckBox.Text = 'Skip Ice';
            app.SkipIrregularCheckBox = uicheckbox(app.MainTab);
            app.SkipIrregularCheckBox.Text = 'Skip Irregular';
            app.SkipShatteringCheckBox = uicheckbox(app.MainTab);
            app.SkipShatteringCheckBox.Text = 'Skip Shattering';
            app.SkipEmptyCheckBox = uicheckbox(app.MainTab);
            app.SkipEmptyCheckBox.Text = 'Skip Empty';
            app.SkipAggregateCheckBox = uicheckbox(app.MainTab);
            app.SkipAggregateCheckBox.Text = 'Skip Aggregate';
            app.SkipMultipleCheckBox = uicheckbox(app.MainTab);
            app.SkipMultipleCheckBox.Text = 'Skip Multiple';
            app.SkipRimedCheckBox = uicheckbox(app.MainTab);
            app.SkipRimedCheckBox.Text = 'Skip Rimed';
            app.SkipCutoffCheckBox = uicheckbox(app.MainTab);
            app.SkipCutoffCheckBox.Text = 'Skip Cut off';
            app.SkipPristineCheckBox = uicheckbox(app.MainTab);
            app.SkipPristineCheckBox.Text = 'Skip Pristine';
            app.SkipElongatedCheckBox = uicheckbox(app.MainTab);
            app.SkipElongatedCheckBox.Text = 'Skip Elongated';
            app.AggregateLampLabel = uilabel(app.MainTab);
            app.AggregateLampLabel.Text = 'Aggregate';
            app.AggregateLamp = uilamp(app.MainTab);
            app.RimedLampLabel = uilabel(app.MainTab);
            app.RimedLampLabel.Text = 'Rimed';
            app.RimedLamp = uilamp(app.MainTab);
            app.PristineLampLabel = uilabel(app.MainTab);
            app.PristineLampLabel.Text = 'Pristine';
            app.PristineLamp = uilamp(app.MainTab);
            app.MultipleLampLabel = uilabel(app.MainTab);
            app.MultipleLampLabel.Text = 'Multiple';
            app.MultipleLamp = uilamp(app.MainTab);
            app.CutoffLampLabel = uilabel(app.MainTab);
            app.CutoffLampLabel.Text = 'Cut off';
            app.CutoffLamp = uilamp(app.MainTab);
            app.ElongatedLampLabel = uilabel(app.MainTab);
            app.ElongatedLampLabel.Text = 'Elongated';
            app.ElongatedLamp = uilamp(app.MainTab);
            app.ChainAggLampLabel = uilabel(app.MainTab);
            app.ChainAggLampLabel.Text = 'Chain Agg.';
            app.ChainAggLamp = uilamp(app.MainTab);
            app.SublimatingLampLabel = uilabel(app.MainTab);
            app.SublimatingLampLabel.Text = 'Sublimating';
            app.SublimatingLamp = uilamp(app.MainTab);
            app.ImageEditFieldLabel = uilabel(app.MainTab);
            app.ImageEditFieldLabel.Text = 'Image';
            app.ImageEditField = uieditfield(app.MainTab, 'numeric');
            app.ImageEditField.ValueChangedFcn = createCallbackFcn(app, @ImageEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.MainTab);
            app.Label_2.Text = '/';
            app.EditField = uieditfield(app.MainTab, 'numeric');
            app.LowLampLabel = uilabel(app.MainTab);
            app.LowLampLabel.Text = 'Low';
            app.LowLamp = uilamp(app.MainTab);
            app.MediumLampLabel = uilabel(app.MainTab);
            app.MediumLampLabel.Text = 'Medium';
            app.MediumLamp = uilamp(app.MainTab);
            app.HighLampLabel = uilabel(app.MainTab);
            app.HighLampLabel.Text = 'High';
            app.HighLamp = uilamp(app.MainTab);
            app.ImageTimeEditFieldLabel = uilabel(app.MainTab);
            app.ImageTimeEditFieldLabel.Text = 'Image Time';
            app.ImageTimeEditField = uieditfield(app.MainTab, 'text');
            app.FlagasinterestingButton = uibutton(app.MainTab, 'push');
            app.FlagasinterestingButton.ButtonPushedFcn = createCallbackFcn(app, @FlagasinterestingButtonPushed, true);
            app.FlagasinterestingButton.Text = 'Flag as "interesting"';
            app.deselectingholdoffnextimageLabel = uilabel(app.MainTab);
            app.deselectingholdoffnextimageLabel.Visible = 'off';
            app.deselectingholdoffnextimageLabel.Text = 'de-selecting = hold off + next image';
            app.ShatteringLampLabel = uilabel(app.MainTab);
            app.ShatteringLampLabel.Text = 'Shattering';
            app.ShatteringLamp = uilamp(app.MainTab);
            app.InterestingFlagLampLabel = uilabel(app.MainTab);
            app.InterestingFlagLampLabel.Text = 'InterestingFlag';
            app.InterestingFlagLamp = uilamp(app.MainTab);
            app.IrregularPotentialShatteringButton = uibutton(app.MainTab, 'push');
            app.IrregularPotentialShatteringButton.ButtonPushedFcn = createCallbackFcn(app, @IrregularPotentialShatteringButtonPushed, true);
            app.IrregularPotentialShatteringButton.Text = {'Irregular + '; ' Potential Shattering'};
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'Read Me';
            app.TextAreaLabel = uilabel(app.ReadMeTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.ReadMeTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'If you assign a "habit" (Plate, Column, ...) to an image, it automatically proceeds to the next image. You can assign as many "attributes" (rimed, aggregate, ...) as you wish.'; ''; 'Multiple Habits: if you want to assign multiple habits to one image (e.g. if you have an aggregate of a plate and a column) press "allow multiple habits" and select the habits. Pressing the button again, turns off the "hold" and proceeds to the next image. As long as "allow multiple habits" is selected, the "Next" button has no effect (technical reasons).'; ''; 'The Button "Irregular + Potential Shattering" was added for convenience to mark the small irregulars that could be potential shattering. This button classifies the image as Irregular = 1 and Shattering = 1 and then proceeds to the next image.'; ''; 'The attribute "flag as interesting" allows to flag individual images as “interesting” for later, so they are easy to find at the end'; ''; ''; 'Known issues: '; '- the "Image Time" field does not work.'; '- if the windows should be out of the range of your monitor, just resize the window a little bit at some point, and it will reposition automatically.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PHIPS_Image_Classifier_8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end